classdef Section3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProgressTextArea             matlab.ui.control.TextArea
        ProgressTextAreaLabel        matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        RESETButton                  matlab.ui.control.Button
        PaperChromatographyAppLabel  matlab.ui.control.Label
        ExperimentFileNameDropDown   matlab.ui.control.DropDown
        ExperimentFileNameDropDownLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        AnalyzeButton                matlab.ui.control.Button
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        LoadExperimentButton         matlab.ui.control.Button
        ExperimentLabel              matlab.ui.control.Label
        LoadStandardsButton          matlab.ui.control.Button
        StandardsLabel               matlab.ui.control.Label
        StandardsFileNameEditField   matlab.ui.control.EditField
        StandardsFileNameEditFieldLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
    end


    properties (Access = private)
        % Loading in items used in code

        Standards = imread("Standards.jpeg");  %loads in the variables I will be using
        Exp1 = imread("Exp1.jpeg");
        Exp2 = imread("Exp2.jpeg");
        Exp3 = imread("Exp3.jpeg");
        Exp4 = imread("Exp4.jpeg");
        Exp5 = imread("Exp5.jpeg");
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadStandardsButton
        function LoadStandardsButtonPushed(app, event)
           
            app.Image.Visible = "on";
            
            app.Image.ImageSource = app.Standards;
            app.StandardsFileNameEditField.Value = 'Standards.jpg';


        end

        % Button pushed function: LoadExperimentButton
        function LoadExperimentButtonPushed(app, event)

                app.Image2.Visible = "on";

            if  strcmp(app.ExperimentFileNameDropDown.Value,"Exp1") == 1 %These tell me which dropdown is active

                app.Image2.ImageSource = app.Exp1;

            elseif strcmp(app.ExperimentFileNameDropDown.Value,"Exp2") == 1

                app.Image2.ImageSource = app.Exp2;

            elseif strcmp(app.ExperimentFileNameDropDown.Value,"Exp3") == 1

                app.Image2.ImageSource = app.Exp3;

            elseif strcmp(app.ExperimentFileNameDropDown.Value,"Exp4") == 1

                app.Image2.ImageSource = app.Exp4;

            else

                app.Image2.ImageSource = app.Exp5;
            end


        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)

            [rowsStandard,columnsStandard] = size(app.Image.ImageSource);
            [rows,columns] = size(app.Image2.ImageSource);

            
            %Progress Box + Analysis


            %if size ~= to size of original
            if rows ~= rowsStandard || columns~= columnsStandard

                %BELOW THIS IS FOR THE ANIMATION

            app.Image3.Visible = "on";
            app.Image3.Position = [88,272,48,50];
            pause(.4);
            app.Image3.Position = [117,248,48,50];
            pause(.4);
            app.Image3.Position = [90,210,48,50];
            pause(.4);


            app.Image3.Position = [391,271,48,50];
            pause(.4);
            app.Image3.Position = [412,248,48,50];
            pause(.4);
            app.Image3.Position = [398,210,48,50];
            pause(.4);

            %ABOVE THIS IS FOR ANIMATION
                
                app.ProgressTextArea.BackgroundColor = [1.00,0.67,0.67];
                app.ProgressTextArea.HorizontalAlignment = 'center';
                app.ProgressTextArea.Value = "The test Strip is a different size than the standards strip!"; 

            %Else we run all the other things
            else

                DisGiven = 620; %given by instructions, Solvent distance

                x = app.Image2.ImageSource; %refrences image source


                Green = x(:,:,2); %setting up for mask
                mask = (x(:,:,1) < 255) & (x(:,:,2) < 255) & (x(:,:,3) < 255); %masking


                amount = size(Green); %finds how big the image is
                xCenter = round(amount(2)/2); %finds center of strip, where marks are


                guy = find(mask(:,xCenter)~=0); % mask

                A = {}; %these allow me to know if the compound shows up at the end
                B = {};
                C = {};
                D = {};


                for i = 1:length(guy)

                    if round((DisGiven-guy(i))/DisGiven,2) == .80
                        A = {'UH-2209'}; %changes A to the name of the compoound if any of those spots = the Rf factor of 2209

                    end

                    if round((DisGiven-guy(i))/DisGiven,2) == .60
                        B = {'UH-3256'};

                    end

                    if round((DisGiven-guy(i))/DisGiven,2) == .40
                        C = {'UH-4112'};

                    end

                    if round((DisGiven-guy(i))/DisGiven,2) == .20
                        D = {'UH-9532'};

                    end

                end

                %Concatanates all the compounds present

                Final =  [A ; B ; C ; D];


                %BELOW THIS IS FOR THE ANIMATION

            app.Image3.Visible = "on";
            app.Image3.Position = [88,272,48,50];
            pause(.4);
            app.Image3.Position = [117,248,48,50];
            pause(.4);
            app.Image3.Position = [90,210,48,50];
            pause(.4);

            
            app.Image3.Position = [391,271,48,50];
            pause(.4);
            app.Image3.Position = [412,248,48,50];
            pause(.4);
            app.Image3.Position = [398,210,48,50];
            pause(.4);

            %ABOVE THIS IS FOR ANIMATION


            app.Image3.Visible = "off";
                %Adds them to the table.

                app.UITable.Data = Final;

                %statement to see if things are in the data table, count how
                %many, and then print that out .

                leng = length(Final);

                %then Display the number of compounds that were
                %identified
                
                
                app.ProgressTextArea.BackgroundColor = [0.46 0.83 0.44];
                app.ProgressTextArea.HorizontalAlignment = 'center';
                app.ProgressTextArea.Value = "There were " + num2str(leng) + " compounds in the test sample";
                
            end


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.StandardsFileNameEditField.Value = ''; %resets the Standards name

            %Reset table

            app.UITable.Data = {}; %resets the table of compounds
            app.ProgressTextArea.Value = ''; %Resets progress

            app.Image3.Visible = "off";

            app.ProgressTextArea.BackgroundColor = [1.00,1.00,1.00];

            %find way to clear images %% Im not sure if this is possible
            %but I would also reset the images.
            %app.Image.ImageSource = [];

            %^^ since that didnt work and I didnt want the user to be
            %confused - "did i reset it" - i just made the image invisible
            %to them. 

            app.Image2.Visible = "off";
            app.Image.Visible = "off";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StandardsFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.StandardsFileNameEditFieldLabel.Text = 'Standards File Name';
            app.StandardsFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.StandardsLabel = uilabel(app.UIFigure);
            app.StandardsLabel.Text = 'Standards';
            app.LoadStandardsButton = uibutton(app.UIFigure, 'push');
            app.LoadStandardsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStandardsButtonPushed, true);
            app.LoadStandardsButton.Text = 'Load Standards';
            app.ExperimentLabel = uilabel(app.UIFigure);
            app.ExperimentLabel.Text = 'Experiment';
            app.LoadExperimentButton = uibutton(app.UIFigure, 'push');
            app.LoadExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentButtonPushed, true);
            app.LoadExperimentButton.Text = 'Load Experiment';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Compounds in Sample'};
            app.UITable.RowName = {};
            app.ExperimentFileNameDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentFileNameDropDownLabel.Text = 'Experiment File Name';
            app.ExperimentFileNameDropDown = uidropdown(app.UIFigure);
            app.ExperimentFileNameDropDown.Items = {'Exp1', 'Exp2', 'Exp3', 'Exp4', 'Exp5'};
            app.ExperimentFileNameDropDown.Value = 'Exp1';
            app.PaperChromatographyAppLabel = uilabel(app.UIFigure);
            app.PaperChromatographyAppLabel.Text = 'Paper Chromatography App';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'target.png');
            app.ProgressTextAreaLabel = uilabel(app.UIFigure);
            app.ProgressTextAreaLabel.Text = 'Progress';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Section3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end