classdef Section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        CeasarCipherEncryptionProgramLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        NameYusufKhanID2165474ProfessorLandonLabel  matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        EncryptedDecryptedtextTextArea  matlab.ui.control.TextArea
        EncryptedDecryptedtextTextAreaLabel  matlab.ui.control.Label
        OriginalTextTextArea            matlab.ui.control.TextArea
        OriginalTextTextAreaLabel       matlab.ui.control.Label
        EncryptDecryptButton            matlab.ui.control.Button
        NumberofShiftsSpinner           matlab.ui.control.Spinner
        NumberofShiftsSpinnerLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description

        Alphabet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyzabcdefghijklmnopqrstuvwxyz';

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptDecryptButton
        function EncryptDecryptButtonPushed(app, event)
               

            %Start Coding screen animation code
            message = 'Running Caesar Cipher code......Please be patient....I am but a computer...';
            runningmessage = '';
            for i = 1:length(message)
                runningmessage =  append(runningmessage,message(i));% Display the current character
                app.TextArea.Value = runningmessage;
                pause(0.05); % Pause for a short time to create the effect of typing
            end
            %Coding Screen Animation code end 
            
            %(new text) Variable to add strings to create final output. 
            ntext = '';
            
            %Encryption Selected
            if app.Switch.Value == 'Encrypt'

                otext = char(app.OriginalTextTextArea.Value);
                
                %for the length of the word
                for w = 1:length(otext)
                    
                    %mainly for debugging issues, but this basically shows
                    %the selected letter
                    selected = otext(w);

                    index1 = strfind(app.Alphabet,selected);

                    %% This code below sure if there is a space we add it to new word
                    if index1 > 0 
                        
                    index2 = index1(1) + mod(app.NumberofShiftsSpinner.Value,26);
                    newChar = app.Alphabet(index2);
                        
                        %Combines the text
                    ntext = append(ntext,newChar);

                    else 
                    
                        %Adds the space
                    ntext = append(ntext,' ');

                    end

                    app.EncryptedDecryptedtextTextArea.Value = ntext;

                end

            else %For Decryption 

                otext = char(app.EncryptedDecryptedtextTextArea.Value);

                for w = 1:length(otext)

                    selected = otext(w);

                    index1 = strfind(app.Alphabet,selected);


                    %% This makes sure if there is a space we add it to old word
                    if index1 > 0 
                        
                    index2 = index1(2) - mod(app.NumberofShiftsSpinner.Value,26);
                    newChar = app.Alphabet(index2);

                    ntext = append(ntext,newChar);

                    else 

                    ntext = append(ntext,' ');

                    end
                        app.OriginalTextTextArea.Value = ntext;

                end
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

                %Resets everything
            app.EncryptedDecryptedtextTextArea.Value = '';
            app.OriginalTextTextArea.Value = '';
            app.NumberofShiftsSpinner.Value = 0;
            app.TextArea.Value = '';
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NumberofShiftsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShiftsSpinnerLabel.Text = 'Number of Shifts';
            app.NumberofShiftsSpinner = uispinner(app.UIFigure);
            app.NumberofShiftsSpinner.Limits = [0 25];
            app.EncryptDecryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptDecryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptDecryptButtonPushed, true);
            app.EncryptDecryptButton.Text = 'Encrypt / Decrypt';
            app.OriginalTextTextAreaLabel = uilabel(app.UIFigure);
            app.OriginalTextTextAreaLabel.Text = 'Original Text ';
            app.OriginalTextTextArea = uitextarea(app.UIFigure);
            app.EncryptedDecryptedtextTextAreaLabel = uilabel(app.UIFigure);
            app.EncryptedDecryptedtextTextAreaLabel.Text = 'Encrypted / Decrypted text';
            app.EncryptedDecryptedtextTextArea = uitextarea(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Decrypt', 'Encrypt'};
            app.Switch.Value = 'Decrypt';
            app.NameYusufKhanID2165474ProfessorLandonLabel = uilabel(app.UIFigure);
            app.NameYusufKhanID2165474ProfessorLandonLabel.Text = {'Name : Yusuf Khan '; 'ID: 2165474'; 'Professor: Landon'};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ComputerScreenPNG.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.CeasarCipherEncryptionProgramLabel = uilabel(app.UIFigure);
            app.CeasarCipherEncryptionProgramLabel.Text = 'Ceasar Cipher Encryption Program';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '+';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Section1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end