classdef Section2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AxialStressStrainandDisplacementCalculatorLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        BeamFailureIndicatorGreenSucessRedFailureLamp  matlab.ui.control.Lamp
        BeamFailureIndicatorGreenSucessRedFailureLabel  matlab.ui.control.Label
        BeamStrainEditField            matlab.ui.control.NumericEditField
        BeamStrainEditFieldLabel       matlab.ui.control.Label
        BeamStressEditField            matlab.ui.control.NumericEditField
        BeamStressEditFieldLabel       matlab.ui.control.Label
        FreeEndDisplacementmEditField  matlab.ui.control.NumericEditField
        FreeEndDisplacementmEditFieldLabel  matlab.ui.control.Label
        MaterialsUltimateStrengthPaEditField  matlab.ui.control.NumericEditField
        MaterialsUltimateStrengthPaEditFieldLabel  matlab.ui.control.Label
        MaterialsYoungsModulusPaEditField  matlab.ui.control.NumericEditField
        MaterialsYoungsModulusPaEditFieldLabel  matlab.ui.control.Label
        TensileStrengthPaEditField     matlab.ui.control.NumericEditField
        TensileStrengthPaEditFieldLabel  matlab.ui.control.Label
        BeamLengthmEditField           matlab.ui.control.NumericEditField
        BeamLengthmEditFieldLabel      matlab.ui.control.Label
        BeamHeightmEditField           matlab.ui.control.NumericEditField
        BeamHeightmEditFieldLabel      matlab.ui.control.Label
        BeamWidthmEditField            matlab.ui.control.NumericEditField
        BeamWidthmEditFieldLabel       matlab.ui.control.Label
        BeamMaterialDropDown           matlab.ui.control.DropDown
        BeamMaterialDropDownLabel      matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        YusufKhan2165474ProfLandonLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description

            %Private variables so I can use them throughout
        SteelYM = 200000000000;
        AluminumYM = 70*(10^9);
        CopperYM = 117*(10^9);
        TitaniumYM = 110*(10^9);

        SteelUTS = 400*(10^6);
        AluminumUTS = 110*(10^6);
        CopperUTS = 220*(10^6);
        TitaniumUTS = 900*(10^6);

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            chosen = app.BeamMaterialDropDown.Value;

                %Case statements to see which material is chosen from the dropdown
            switch chosen
                case 'Steel'
                    app.MaterialsYoungsModulusPaEditField.Value = app.SteelYM;
                    app.MaterialsUltimateStrengthPaEditField.Value = app.SteelUTS;
                case 'Aluminum'
                    app.MaterialsYoungsModulusPaEditField.Value = app.AluminumYM;
                    app.MaterialsUltimateStrengthPaEditField.Value = app.AluminiumUTS;
                case 'Copper'
                    app.MaterialsYoungsModulusPaEditField.Value = app.CopperYM;
                    app.MaterialsUltimateStrengthPaEditField.Value = app.CopperUTS;
                case 'Titanium Alloy'
                    app.MaterialsYoungsModulusPaEditField.Value = app.TitaniumYM;
                    app.MaterialsUltimateStrengthPaEditField.Value = app.TitaniumUTS;
            end

                %% Calculations 
               
                pause(1); %to give some time before answers load in
            nume = (app.MaterialsYoungsModulusPaEditField.Value * app.BeamWidthmEditField.Value * app.BeamHeightmEditField.Value);
            deno = (app.TensileStrengthPaEditField.Value * app.BeamLengthmEditField.Value);
                
                %Free-end Displacement
            app.FreeEndDisplacementmEditField.Value = nume/deno;
                
                %Stress & Strain
            app.BeamStressEditField.Value = app.TensileStrengthPaEditField.Value /(app.BeamWidthmEditField.Value * app.BeamHeightmEditField.Value);
            app.BeamStrainEditField.Value = app.FreeEndDisplacementmEditField.Value/app.BeamLengthmEditField.Value;

                %If - chooses whether to make the lamp green or red based
                %on is the beam Strength and Beam Stress 
                
                pause(.5); %to give some time before the lamp goes on
            if app.MaterialsUltimateStrengthPaEditField.Value < app.BeamStressEditField.Value
                app.BeamFailureIndicatorGreenSucessRedFailureLamp.Color='r';
            else
                app.BeamFailureIndicatorGreenSucessRedFailureLamp.Color='g';
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)


                %Resets everything 
            app.BeamFailureIndicatorGreenSucessRedFailureLamp.Color = 'white';
            app.BeamLengthmEditField.Value = 0;
            app.BeamHeightmEditField.Value = 0;
            app.BeamWidthmEditField.Value = 0;
            app.BeamStrainEditField.Value = 0;
            app.BeamStressEditField.Value = 0;
            app.FreeEndDisplacementmEditField.Value = 0;
            app.TensileStrengthPaEditField.Value = 0;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.YusufKhan2165474ProfLandonLabel = uilabel(app.UIFigure);
            app.YusufKhan2165474ProfLandonLabel.Text = {'Yusuf Khan'; '2165474'; 'Prof: Landon'};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BeamMaterialDropDownLabel = uilabel(app.UIFigure);
            app.BeamMaterialDropDownLabel.Text = 'Beam Material';
            app.BeamMaterialDropDown = uidropdown(app.UIFigure);
            app.BeamMaterialDropDown.Items = {'Steel', 'Aluminum', 'Copper', 'Titanium Alloy'};
            app.BeamMaterialDropDown.Value = 'Steel';
            app.BeamWidthmEditFieldLabel = uilabel(app.UIFigure);
            app.BeamWidthmEditFieldLabel.Text = 'Beam Width [m]';
            app.BeamWidthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamWidthmEditField.Limits = [0 Inf];
            app.BeamHeightmEditFieldLabel = uilabel(app.UIFigure);
            app.BeamHeightmEditFieldLabel.Text = 'Beam Height [m]';
            app.BeamHeightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamHeightmEditField.Limits = [0 Inf];
            app.BeamLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.BeamLengthmEditFieldLabel.Text = 'Beam Length [m]';
            app.BeamLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamLengthmEditField.Limits = [0 Inf];
            app.TensileStrengthPaEditFieldLabel = uilabel(app.UIFigure);
            app.TensileStrengthPaEditFieldLabel.Text = 'Tensile Strength [Pa]';
            app.TensileStrengthPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TensileStrengthPaEditField.Limits = [0 Inf];
            app.MaterialsYoungsModulusPaEditFieldLabel = uilabel(app.UIFigure);
            app.MaterialsYoungsModulusPaEditFieldLabel.Text = 'Materials Young''s Modulus [Pa] ';
            app.MaterialsYoungsModulusPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaterialsUltimateStrengthPaEditFieldLabel = uilabel(app.UIFigure);
            app.MaterialsUltimateStrengthPaEditFieldLabel.Text = 'Materials Ultimate Strength [Pa]';
            app.MaterialsUltimateStrengthPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreeEndDisplacementmEditFieldLabel = uilabel(app.UIFigure);
            app.FreeEndDisplacementmEditFieldLabel.Text = 'Free End Displacement [m]';
            app.FreeEndDisplacementmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamStressEditFieldLabel = uilabel(app.UIFigure);
            app.BeamStressEditFieldLabel.Text = 'Beam Stress';
            app.BeamStressEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamStrainEditFieldLabel = uilabel(app.UIFigure);
            app.BeamStrainEditFieldLabel.Text = 'Beam Strain';
            app.BeamStrainEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamFailureIndicatorGreenSucessRedFailureLabel = uilabel(app.UIFigure);
            app.BeamFailureIndicatorGreenSucessRedFailureLabel.Text = {'Beam Failure Indicator '; 'Green = Sucess'; 'Red = Failure'};
            app.BeamFailureIndicatorGreenSucessRedFailureLamp = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AxialStressStrainandDisplacementCalculatorLabel = uilabel(app.UIFigure);
            app.AxialStressStrainandDisplacementCalculatorLabel.Text = 'Axial Stress, Strain, and Displacement Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Section2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end